classdef IRIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InteractiveResourceImpactSimulationJoshuaAngeUIFigure  matlab.ui.Figure
        Background                  matlab.ui.control.Image
        INTERACTIVERESOURCEIMPACTSIMULATIONLabel  matlab.ui.control.Label
        VersionNumber               matlab.ui.control.Label
        Logo                        matlab.ui.control.Image
        GraphicsPanel               matlab.ui.container.Panel
        Main                        matlab.ui.control.UIAxes
        Second                      matlab.ui.control.UIAxes
        DeformationButton           matlab.ui.control.Button
        ReactionsButton             matlab.ui.control.Button
        SurvivabilityButton         matlab.ui.control.Button
        VelocityButton              matlab.ui.control.Button
        EnergyButton                matlab.ui.control.Button
        SpinButton                  matlab.ui.control.Button
        TemperatureButton           matlab.ui.control.Button
        LogPanel                    matlab.ui.container.Panel
        ParametersPanel             matlab.ui.container.TabGroup
        InitialConditionsTab        matlab.ui.container.Tab
        Button                      matlab.ui.control.Button
        PodInformationTab           matlab.ui.container.Tab
        EnvironmentalConditionsTab  matlab.ui.container.Tab
        ProgramDataTab              matlab.ui.container.Tab
    end

    
    properties (Access = public)
        folder = pwd;
        file = 'PTHeight_CraterTest';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            hold (app.Main,"on");
            grid (app.Main,"minor");
            view (app.Main,3);
            colormap(app.Main,"gray")
            xlim(app.Main,[0 L_domain]);
            ylim(app.Main,[0 L_domain]);
            zlim(app.Main,[0 L_range]);
            %surfc(app.Main,L_x, L_y, L_z,'EdgeAlpha',0.1,'FaceAlpha',1,'DisplayName','Surface Topology') % Mesh Plot
            
             for iit = 1:i_max
                 if isempty(b{iit}) == 0
                    fplot3(app.Main,b{iit}.x_i,b{iit}.y_i,b{iit}.z_i,[0 double(b{iit}.t_f_i)],'DisplayName','Trajectory','lineWidth',1,'Color','black');
                 end
                 for jit = 1:j_max
                     if isempty(s{iit,jit}) == 0
                        disp(jit);
                        quiver3(app.Main,s{iit,jit}.O_ij(1,1),s{iit,jit}.O_ij(1,2),s{iit,jit}.O_ij(1,3),s{iit,jit}.O_ij(2,1),s{iit,jit}.O_ij(2,2),s{iit,jit}.O_ij(2,3),'lineWidth',1,'Color','black','MaxHeadSize',0.5,'DisplayName','Traced Path Segment');
                     end
                 end
             end
             hold (app.Main,"off");
        end

        % Button pushed function: TemperatureButton
        function TemperatureButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    for iteration = 1:floor(double(b{iit}.t_f_i)/T_units)
                        multitude = iteration * T_units;
                        plot(app.Second,timeA+multitude,b{iit}.T_i,'o','Color','blue','HandleVisibility','off');
                    end
                    timeA = timeA + b{iit}.t_f_i;
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        plot(app.Second,timeA,s{iit,jit}.T_s_ij,'^','Color','blue','HandleVisibility','off');
                        timeA = timeA + T;
                        plot(app.Second,timeA,s{iit,jit}.T_f_ij,'^','Color','blue','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Temperature (K)');
            title(app.Second,'Temperature');
            disp("Temperature Graph");
        end

        % Button pushed function: DeformationButton
        function DeformationButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            plot(app.Second,0,0,'^','Color','blue','HandleVisibility','on','DisplayName',"Elastic Compression");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    timeA = timeA + b{iit}.t_f_i;
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        timeA = timeA + T;
                        plot(app.Second,(timeA),vpa(s{iit,jit}.D_ij),'^','Color','blue','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Elastic Compression (m)');
            title(app.Second,'Deformation');
            disp("Deformation Graph");
        end

        % Button pushed function: EnergyButton
        function EnergyButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            a = plot(app.Second,0,double(b{1}.KE_s_i),'d','Color','blue','HandleVisibility','on','DisplayName',"Kinetic Energy");
            b = plot(app.Second,0,0,'o','Color','red','HandleVisibility','on','DisplayName',"Potential Energy");
            c = plot(app.Second,0,0,'s','Color','black','HandleVisibility','on','DisplayName',"Total Energy");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    for iteration = 1:floor(b{iit}.t_f_i/T_units)
                        multitude = iteration * T_units;
                        plot(app.Second,double(timeA+multitude),double(0.5*m*mag([b{iit}.x_i(multitude) b{iit}.y_i(multitude) b{iit}.z_i(multitude); b{iit}.dx_i(multitude) b{iit}.dy_i(multitude) b{iit}.dz_i(multitude)])^2),'d','Color','blue','HandleVisibility','off');  
                        plot(app.Second,double(timeA+multitude),m*g*(b{iit}.z_i(multitude)-L(b{iit}.x_i(multitude),b{iit}.y_i(multitude))),'o','Color','red','HandleVisibility','off');
                        plot(app.Second,double(timeA+multitude),double(0.5*m*mag([b{iit}.x_i(multitude) b{iit}.y_i(multitude) b{iit}.z_i(multitude); b{iit}.dx_i(multitude) b{iit}.dy_i(multitude) b{iit}.dz_i(multitude)])^2)+m*g*(b{iit}.z_i(multitude)-L(b{iit}.x_i(multitude),b{iit}.y_i(multitude))),'s','Color','black','HandleVisibility','off');
                    end
                    timeA = timeA + b{iit}.t_f_i;
                    plot(app.Second,timeA,b{iit}.KE_f_i,'d','Color','blue','HandleVisibility','off');
                    plot(app.Second,timeA,0,'o','Color','red','HandleVisibility','off');    
                    plot(app.Second,timeA,b{iit}.KE_f_i,'s','Color','black','HandleVisibility','off');  
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        plot(app.Second,(timeA),double(s{iit,jit}.KE_s_ij),'d','Color','blue','HandleVisibility','off');
                        timeA = timeA + T;
                        plot(app.Second,(timeA),double(s{iit,jit}.KE_f_ij),'d','Color','blue','HandleVisibility','off');
                        plot(app.Second,(timeA),0,'o','Color','red','HandleVisibility','off');    
                        plot(app.Second,(timeA),double(s{iit,jit}.KE_f_ij),'s','Color','black','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[double(b{1}.t_f_i), double(timeA+T)]);
            legend(app.Second,[a b c],{'Kinetic Energy' 'Potential Energy' 'Total Energy'});
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Energy (J)');
            title(app.Second,'Kinetic Energy');
            disp("Energy Graph");
        end

        % Button pushed function: ReactionsButton
        function ReactionsButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            d = plot(app.Second,0,0,'o','Color','green','DisplayName',"Initial Velocity");
            e = plot(app.Second,0,0,'^','Color','black','DisplayName',"Rotational");
            f = plot(app.Second,0,0,'^','Color','green','DisplayName',"Normal");
            g = plot(app.Second,0,0,'s','Color','blue','DisplayName',"Friction");
            h = plot(app.Second,0,0,'s','Color','green','DisplayName',"Gravity");
            i = plot(app.Second,0,0,'^','Color','red','DisplayName',"Elastic");
            j = plot(app.Second,0,0,'o','Color','red','DisplayName',"Final Velocity");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    timeA = timeA + b{iit}.t_f_i;
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        timeA = timeA + T;
                        plot(app.Second,timeA,mag(s{iit,jit}.v_ij),'o','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.A_r_ij),'^','Color','black','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.A_n_ij),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.A_f_ij),'s','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.A_g_ij),'s','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.A_e_ij),'^','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.V_ij),'o','Color','red','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            ylim(app.Second,[0, 100]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Velocity Influence (m/s)');
            title(app.Second,'Reactionary Forces');
            legend(app.Second,d, e, f, g, h, i, j,'Initial Velocity', 'Rotational', 'Normal', 'Friction', 'Gravity', 'Elastic', 'Final Velocity');
            disp("Reaction Graph");
        end

        % Button pushed function: SpinButton
        function SpinButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            k = plot(app.Second,0,q_i(2,1),'o','Color','blue','DisplayName',"X");
            l = plot(app.Second,0,q_i(2,2),'^','Color','green','DisplayName',"Y");
            m = plot(app.Second,0,q_i(2,3),'s','Color','red','DisplayName',"Z");
            n = plot(app.Second,0,mag(u_i),'+','Color','magenta','DisplayName',"Total");
            o = plot(app.Second,0,0,'o','Color','black','DisplayName',"X Influence");
            p = plot(app.Second,0,0,'^','Color','black','DisplayName',"Y Influence");
            q = plot(app.Second,0,0,'s','Color','black','DisplayName',"Z Influence");
            r = plot(app.Second,0,0,'+','Color','black','DisplayName',"Total Influence");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    for iteration = 1:floor(double(b{iit}.t_f_i)/T_units)
                        multitude = iteration * T_units;
                        plot(app.Second,timeA+multitude,b{iit}.q_i(2,1),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,b{iit}.q_i(2,2),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,b{iit}.q_i(2,3),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,mag(b{iit}.q_i),'+','Color','magenta','HandleVisibility','off');
                    end
                    timeA = timeA + b{iit}.t_f_i;
                    plot(app.Second,timeA,b{iit}.q_i(2,1),'o','Color','blue','HandleVisibility','off');
                    plot(app.Second,timeA,b{iit}.q_i(2,2),'^','Color','green','HandleVisibility','off');
                    plot(app.Second,timeA,b{iit}.q_i(2,3),'s','Color','red','HandleVisibility','off');
                    plot(app.Second,timeA,mag(b{iit}.q_i),'+','Color','magenta','HandleVisibility','off');
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        plot(app.Second,timeA,s{iit,jit}.q_ij(2,1),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.q_ij(2,2),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.q_ij(2,3),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.q_ij),'+','Color','magenta','HandleVisibility','off');
                        timeA = timeA + 0.5*T;
                        plot(app.Second,timeA,s{iit,jit}.p_ij(2,1),'o','Color','black','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.p_ij(2,2),'^','Color','black','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.p_ij(2,3),'s','Color','black','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.p_ij),'+','Color','black','HandleVisibility','off');
                        timeA = timeA + 0.5*T;
                        plot(app.Second,timeA,s{iit,jit}.Q_ij(2,1),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.Q_ij(2,1),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.Q_ij(2,1),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.Q_ij),'+','Color','magenta','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Velocity (rad/s)');
            legend(app.Second,[k l m n o p q r],{'X' 'Y' 'Z' 'Total' 'X Influence' 'Y Influence' 'Z Influence' 'Total Influence'});
            title(app.Second,'Spin');
            disp("Spin Graph");
        end

        % Button pushed function: SurvivabilityButton
        function SurvivabilityButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            s = plot(app.Second,0,0,'o','Color','blue','DisplayName',"Pod");
            t = plot(app.Second,0,0,'^','Color','red','DisplayName',"Contents");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    timeA = timeA + b{iit}.t_f_i;
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        timeA = timeA + T;
                        plot(app.Second,timeA,s{iit,jit}.S_pod_ij,'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.S_contents_ij,'^','Color','red','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Survivability Metric');
            legend(app.Second,[s t],{'Pod' 'Contents'});
            title(app.Second,'Survivability');
            disp("Survivability Graph");
        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            u = plot(app.Second,0,u_i(2,1),'o','Color','blue','DisplayName',"X");
            v = plot(app.Second,0,u_i(2,2),'^','Color','green','DisplayName',"Y");
            w = plot(app.Second,0,u_i(2,3),'s','Color','red','DisplayName',"Z");
            x = plot(app.Second,0,mag(u_i),'+','Color','magenta','DisplayName',"Total");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    for iteration = 1:floor(double(b{iit}.t_f_i)/T_units)
                        multitude = iteration * T_units;
                        plot(app.Second,timeA+multitude,b{iit}.dx_i(multitude),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,b{iit}.dy_i(multitude),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,b{iit}.dz_i(multitude),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,mag([0,0,0;b{iit}.dx_i(multitude),b{iit}.dy_i(multitude),b{iit}.dz_i(multitude)]),'+','Color','magenta','HandleVisibility','off');
                    end
                    timeA = timeA + b{iit}.t_f_i;
                    plot(app.Second,timeA,b{iit}.v_i(2,1),'o','Color','blue','HandleVisibility','off');
                    plot(app.Second,timeA,b{iit}.v_i(2,2),'^','Color','green','HandleVisibility','off');
                    plot(app.Second,timeA,b{iit}.v_i(2,3),'s','Color','red','HandleVisibility','off');
                    plot(app.Second,timeA,mag(b{iit}.v_i),'+','Color','magenta','HandleVisibility','off');
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        timeA = timeA + 0.5*T;
                        plot(app.Second,timeA,0.5*s{iit,jit}.v_ij(2,1)+0.5*s{iit,jit}.V_ij(2,1),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,0.5*s{iit,jit}.v_ij(2,2)+0.5*s{iit,jit}.V_ij(2,2),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,0.5*s{iit,jit}.v_ij(2,3)+0.5*s{iit,jit}.V_ij(2,3),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA,0.5*mag(s{iit,jit}.v_ij)+0.5*mag(s{iit,jit}.V_ij),'+','Color','magenta','HandleVisibility','off');
                        timeA = timeA + 0.5*T;
                        plot(app.Second,timeA,s{iit,jit}.V_ij(2,1),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.V_ij(2,2),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.V_ij(2,3),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.V_ij),'+','Color','magenta','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Velocity (m/s)');
            legend(app.Second,[u v w x],{'X' 'Y' 'Z' 'Total'});
            title(app.Second,'Velocity');
            disp("Velocity Graph");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure = uifigure('Visible', 'off');
            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure.Name = 'Interactive Resource Impact Simulation - Joshua Ange';
            app.Background = uiimage(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Background.ImageSource = 'Blue_Background.png';
            app.INTERACTIVERESOURCEIMPACTSIMULATIONLabel = uilabel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.INTERACTIVERESOURCEIMPACTSIMULATIONLabel.Text = 'INTERACTIVE RESOURCE IMPACT SIMULATION';
            app.VersionNumber = uilabel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.VersionNumber.Text = '1.5.0';
            app.Logo = uiimage(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Logo.ImageSource = 'IRIS_White.png';
            app.GraphicsPanel = uipanel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Main = uiaxes(app.GraphicsPanel);
            title(app.Main, {'Supply Pod Motion'; ''})
            xlabel(app.Main, 'X')
            ylabel(app.Main, 'Y')
            app.Second = uiaxes(app.GraphicsPanel);
            title(app.Second, 'Survivability Metrics')
            xlabel(app.Second, 'X')
            ylabel(app.Second, 'Y')
            app.DeformationButton = uibutton(app.GraphicsPanel, 'push');
            app.DeformationButton.ButtonPushedFcn = createCallbackFcn(app, @DeformationButtonPushed, true);
            app.DeformationButton.Text = 'Deformation';
            app.ReactionsButton = uibutton(app.GraphicsPanel, 'push');
            app.ReactionsButton.ButtonPushedFcn = createCallbackFcn(app, @ReactionsButtonPushed, true);
            app.ReactionsButton.Text = 'Reactions';
            app.SurvivabilityButton = uibutton(app.GraphicsPanel, 'push');
            app.SurvivabilityButton.ButtonPushedFcn = createCallbackFcn(app, @SurvivabilityButtonPushed, true);
            app.SurvivabilityButton.Text = 'Survivability';
            app.VelocityButton = uibutton(app.GraphicsPanel, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed, true);
            app.VelocityButton.Text = 'Velocity';
            app.EnergyButton = uibutton(app.GraphicsPanel, 'push');
            app.EnergyButton.ButtonPushedFcn = createCallbackFcn(app, @EnergyButtonPushed, true);
            app.EnergyButton.Text = 'Energy';
            app.SpinButton = uibutton(app.GraphicsPanel, 'push');
            app.SpinButton.ButtonPushedFcn = createCallbackFcn(app, @SpinButtonPushed, true);
            app.SpinButton.Text = 'Spin';
            app.TemperatureButton = uibutton(app.GraphicsPanel, 'push');
            app.TemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @TemperatureButtonPushed, true);
            app.TemperatureButton.Text = 'Temperature';
            app.LogPanel = uipanel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.ParametersPanel = uitabgroup(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.InitialConditionsTab = uitab(app.ParametersPanel);
            app.InitialConditionsTab.Title = 'Initial Conditions';
            app.Button = uibutton(app.InitialConditionsTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PodInformationTab = uitab(app.ParametersPanel);
            app.PodInformationTab.Title = 'Pod Information';
            app.EnvironmentalConditionsTab = uitab(app.ParametersPanel);
            app.EnvironmentalConditionsTab.Title = 'Environmental Conditions';
            app.ProgramDataTab = uitab(app.ParametersPanel);
            app.ProgramDataTab.Title = 'Program Data';
            % Show the figure after all components are created
            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure)
        end
    end
end